classdef Turn_Segmentation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        FigurePanel            matlab.ui.container.Panel
        UIAxes1                matlab.ui.control.UIAxes
        FunctionPanel          matlab.ui.container.Panel
        ConfirmButton          matlab.ui.control.Button
        subButton              matlab.ui.control.Button
        addButton              matlab.ui.control.Button
        FigureButton           matlab.ui.control.Button
        SaveButton             matlab.ui.control.Button
        File_OKButton          matlab.ui.control.Button
        Path_FilePanel         matlab.ui.container.Panel
        UITable                matlab.ui.control.Table
        SavePathTextArea       matlab.ui.control.TextArea
        SavePathTextAreaLabel  matlab.ui.control.Label
        FilePathTextArea       matlab.ui.control.TextArea
        FilePathTextAreaLabel  matlab.ui.control.Label
    end

%% -----------------------------------------------------------------------------------------------------------
% Turn Segmentation from Trunk IMU Data
% Author: Jiahui Wang, PhD Candidate, KU Leuven
% First created: 2023-10-14
%
% If you use this app, please cite:
% Wang J, Sergooris A, Corten K, Timmermans AAA, Vanwanseele B.
% Evaluating Gait Quality in People with Hip Osteoarthritis During Habitual
% and Fast Walking Using a Trunk Inertial Measurement Unit in Clinical Settings.
% Sensors. 2026;26(3):820.
% https://pmc.ncbi.nlm.nih.gov/articles/PMC12899795/
%-------------------------------------------------------------------------------------------------------------
    properties (Access = private)
        CutData % Description
    end
    
    methods (Access = private)
        
        function func_draw(app,cursor_info_now)
            idx = app.CutData.Idx_MAT;
            NumCal = app.CutData.Num_MAT;

            app.UITable.BackgroundColor = app.CutData.BackgroundColor;
            app.UITable.BackgroundColor(idx,:) = [0.6,0.7,0.8];
            Pace_Tilt_convert = app.CutData.Data_Origin{idx};
            Data_ipt  = table2array(Pace_Tilt_convert);
            Gvert   = Data_ipt(:,6);  % 对应Gvert
            Gvert_norm = (Gvert)/max(abs(Gvert));
            
            if ( nargin == 2 )
                for idx_mabual = 1:length(cursor_info_now)
                    pos_cut(idx_mabual) = cursor_info_now(idx_mabual).X; 
                end
                cla(app.UIAxes1)
                plot(app.UIAxes1,Gvert_norm)
                app.UIAxes1.Title.String = ['Gvert Norm(Num:',num2str(NumCal),',Idx:',num2str(idx),')'];
                hold(app.UIAxes1,'on');
                plot(app.UIAxes1,pos_cut,Gvert_norm(pos_cut),'ro');
                hold(app.UIAxes1,'on');
                plot(app.UIAxes1,pos_cut,Gvert_norm(pos_cut),'k*');
              
                app.CutData.pos_cut = sort(pos_cut);
            else
                cla(app.UIAxes1)
                plot(app.UIAxes1,Gvert_norm)
                app.UIAxes1.Title.String = ['Gvert Norm(Num:',num2str(NumCal),',Idx:',num2str(idx),')'];
      
            end

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: File_OKButton
        function File_OKButtonPushed(app, event)
            FilePath = app.FilePathTextArea.Value{1};
            SavePath = app.SavePathTextArea.Value{1};
            if (~isempty(FilePath)&&~isempty(SavePath))
                app.CutData.FilePath = FilePath;
                app.CutData.SavePath = SavePath;
                Mat_Path = dir(strcat(FilePath,'\*.mat'));
                app.CutData.Num_MAT = length(Mat_Path);
                for idx = 1:length(Mat_Path)
                    FileName{idx} = Mat_Path(idx).name;
                    Data_Table{idx,1} = idx;
                    Data_Table{idx,2} = FileName{idx};
                    app.CutData.Mat_Path{idx}= strcat(FilePath,'\',FileName{idx});
                    app.CutData.Mat_Name{idx}= FileName{idx};
                    load(app.CutData.Mat_Path{idx});
                    app.CutData.Data_Origin{idx} = Pace_Tilt_convert;
                    if (mod(idx,2)==1)
                        BackgroundColor(idx,:) = [1,1,1];
                    else
                        BackgroundColor(idx,:) = [0.9,0.9,0.9];
                    end
                end
                app.UITable.Data = Data_Table;
                app.UITable.BackgroundColor = BackgroundColor;

                app.CutData.BackgroundColor = BackgroundColor;
                app.CutData.Idx_MAT = 1;
            end
        end

        % Button pushed function: subButton
        function subButtonPushed(app, event)
            FilePath = app.CutData.FilePath;
            SavePath = app.CutData.SavePath;
            if (~isempty(FilePath)&&~isempty(SavePath))
                if (~isempty(app.CutData.Mat_Path))
                    app.CutData.Idx_MAT = app.CutData.Idx_MAT-1;
                    if ( app.CutData.Idx_MAT < 1)
                        app.CutData.Idx_MAT = app.CutData.Num_MAT;
                    end
                    func_draw(app);
                end
            end
        end

        % Button pushed function: addButton
        function addButtonPushed(app, event)
            FilePath = app.CutData.FilePath;
            SavePath = app.CutData.SavePath;
            if (~isempty(FilePath)&&~isempty(SavePath))
                if (~isempty(app.CutData.Mat_Path))
                    app.CutData.Idx_MAT = app.CutData.Idx_MAT+1;
                    if ( app.CutData.Idx_MAT > app.CutData.Num_MAT)
                        app.CutData.Idx_MAT = 1;
                    end
                    func_draw(app);
                end
            end   
        end

        % Button pushed function: FigureButton
        function FigureButtonPushed(app, event)
            FilePath = app.CutData.FilePath;
            SavePath = app.CutData.SavePath;
            if (~isempty(FilePath)&&~isempty(SavePath))
                if (~isempty(app.CutData.Mat_Path))
                    func_draw(app);
                end
            end
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            FilePath = app.CutData.FilePath;
            SavePath = app.CutData.SavePath;
            if (~isempty(FilePath)&&~isempty(SavePath))
                if (~isempty(app.CutData.Mat_Path))
                    cursor_info_now = [];
                    for i = 1:length(app.UIAxes1.Children)
                        cursor_info_now = [cursor_info_now;app.UIAxes1.Children(i).Children];
                    end
                    if (~isempty(cursor_info_now) && (mod(length(cursor_info_now),2)==0) )
                        func_draw(app,cursor_info_now);
                    else
                        errordlg('At least even pos!');
                    end
                end
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            FilePath = app.CutData.FilePath;
            SavePath = app.CutData.SavePath;
            if (~isempty(FilePath)&&~isempty(SavePath))
                if (~isempty(app.CutData.Mat_Path))
                    if (~isempty(app.CutData.pos_cut))
                        idx = app.CutData.Idx_MAT;
                        Pace_Tilt_convert = app.CutData.Data_Origin{idx};
                        Data_ipt  = table2array(Pace_Tilt_convert);
                        headers = {'time', 'Avert', 'Amedlat', 'Aantpost', 'ATotal', 'Gvert', 'Gmedlat', 'Gantpost'};
                        FileName = app.CutData.Mat_Name{app.CutData.Idx_MAT};
                        [old_name_cell,~] = strsplit(FileName(1:end-4),'_');
                        old_name_cell{2} = 'TurnData';
                        L_old_name_cell = length(old_name_cell);
                        start_frame_old = str2num( old_name_cell{L_old_name_cell-1} );
                        pos_opt = app.CutData.pos_cut;
                        for idx_name = 1:length(pos_opt)/2
                            new_name0 = [old_name_cell{1},'_',old_name_cell{2},'_T',num2str(idx_name)]; 
                            Data_test_tmp0 = Data_ipt(pos_opt((idx_name-1)*2+1):pos_opt(idx_name*2),:);
                            Pace_Tilt_convert = array2table(Data_test_tmp0,'VariableNames',headers);  % 测试数据存储
                            save ([SavePath,'\',new_name0],"Pace_Tilt_convert" );
                        end
                    end
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Path_FilePanel = uipanel(app.UIFigure);
            app.Path_FilePanel.Title = 'Path_File';
            app.FilePathTextAreaLabel = uilabel(app.Path_FilePanel);
            app.FilePathTextAreaLabel.Text = 'FilePath';
            app.FilePathTextArea = uitextarea(app.Path_FilePanel);
            app.SavePathTextAreaLabel = uilabel(app.Path_FilePanel);
            app.SavePathTextAreaLabel.Text = 'SavePath';
            app.SavePathTextArea = uitextarea(app.Path_FilePanel);
            app.UITable = uitable(app.Path_FilePanel);
            app.UITable.ColumnName = {'Idx'; 'MATFile'};
            app.UITable.RowName = {};
            app.FunctionPanel = uipanel(app.UIFigure);
            app.FunctionPanel.Title = 'Function';
            app.File_OKButton = uibutton(app.FunctionPanel, 'push');
            app.File_OKButton.ButtonPushedFcn = createCallbackFcn(app, @File_OKButtonPushed, true);
            app.File_OKButton.Text = 'File_OK';
            app.SaveButton = uibutton(app.FunctionPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.FigureButton = uibutton(app.FunctionPanel, 'push');
            app.FigureButton.ButtonPushedFcn = createCallbackFcn(app, @FigureButtonPushed, true);
            app.FigureButton.Text = 'Figure';
            app.addButton = uibutton(app.FunctionPanel, 'push');
            app.addButton.ButtonPushedFcn = createCallbackFcn(app, @addButtonPushed, true);
            app.addButton.Text = '+';
            app.subButton = uibutton(app.FunctionPanel, 'push');
            app.subButton.ButtonPushedFcn = createCallbackFcn(app, @subButtonPushed, true);
            app.subButton.Text = '-';
            app.ConfirmButton = uibutton(app.FunctionPanel, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Text = 'Confirm';
            app.FigurePanel = uipanel(app.UIFigure);
            app.FigurePanel.Title = 'Figure';
            app.UIAxes1 = uiaxes(app.FigurePanel);
            title(app.UIAxes1, 'Title')
            xlabel(app.UIAxes1, 'Point')
            ylabel(app.UIAxes1, 'Amp')
            zlabel(app.UIAxes1, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Turn_Segmentation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end